classdef LabelRecognition < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LabelRecognitionUIFigure    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        LeftGridLayout              matlab.ui.container.GridLayout
        FileLocationField           matlab.ui.control.EditField
        BrowseButton                matlab.ui.control.Button
        RecogniseDatesButton        matlab.ui.control.Button
        ConfigurationLabel          matlab.ui.control.Label
        MSERDeltaLabel              matlab.ui.control.Label
        MSERDeltaSlider             matlab.ui.control.Slider
        SWTThresholdLabel           matlab.ui.control.Label
        SWTThresholdSlider          matlab.ui.control.Slider
        OrientationThresholdLabel   matlab.ui.control.Label
        OrientationThresholdSlider  matlab.ui.control.Slider
        DetectedDatesPanel          matlab.ui.container.Panel
        DetectedDatesGridLayout     matlab.ui.container.GridLayout
        SaveButton                  matlab.ui.control.Button
        DetectedDatesTextArea       matlab.ui.control.TextArea
        ResetButton                 matlab.ui.control.Button
        RightPanel                  matlab.ui.container.Panel
        RightGridLayout             matlab.ui.container.GridLayout
        Image                       matlab.ui.control.Image
        RuntimeLabel                matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Properties that correspond to internal app data
    properties (Access = private)
        lr = LabelRecogniser();
    end
    
    % Methods that correspond to internal app behaviour
    methods (Access = private)
        
        function SelectImage(app)
            % Define valid file types
            types = {'*.jpeg;*.jpg;*.png;*.gif;*.tiff', ...
                'Image Files (*.jpeg, *.jpg, *.png, *.gif, *.tiff)'};
            % Open file explorer
            [file, path] = uigetfile(types, 'Browse Images');
            
            % Check that a file was selected
            if ~isequal(file, 0)
                % Get full path
                filepath = fullfile(path, file);
                
                % Attempt to read image to M-by-N matrix
                try 
                    I = imread(filepath);
                catch ME
                    uialert(app.LabelRecognitionUIFigure, ME.message, 'Invalid File Type');
                end
                
                % Assign selected image to variables and GUI components
                AssignImageValues(app, I, filepath);
                % Mark the shown runtime as outdated
                ShowRuntimeAsOutdated(app);
                % Clear the detected dates box
                app.DetectedDatesTextArea.Value = "";
                ToggleSaveButtonStatus(app);
            end
        end
        
        function SaveDateFile(app)
            % Define valid file types and get currently selected image name
            type = {'*.txt', 'Text File (*.txt)'};
            [~, name, ~] = fileparts(app.FileLocationField.Value);
            
            % Create Save As dialog
            [file, path] = uiputfile(type, 'Save As', name);
            
            % Ensure a directory was selected for saving 
            if ~isequal(file, 0)
                % Write dates to file
                fileID = fopen(fullfile(path, file), 'w');
                fprintf(fileID, '%s\n', app.DetectedDatesTextArea.Value{:});
                fclose(fileID);
            end
        end
        
        function AssignImageValues(app, userImage, imagePath)
            % Ensure M-by-N-by-3 matrix is always assigned to image
            if size(userImage, 3) < 3
                app.Image.ImageSource = cat(3, userImage, userImage, userImage);
            else
                app.Image.ImageSource = userImage;
            end
            % Make image visible
            app.Image.Visible = 'on';
            % Assign selected file to field and image box
            app.FileLocationField.Value = imagePath;
            % Assign image matrix to Label Recogniser object 
            app.lr.image = userImage;
        end
    
        function ToggleSaveButtonStatus(app)
            if isempty(app.DetectedDatesTextArea.Value{1})
                app.SaveButton.Enable = 'off';
            else
                app.SaveButton.Enable = 'on';
            end
        end
    
        function UpdateDatesTextArea(app, dates)
            if isempty(dates)
                % Raise error for no date found
                app.DetectedDatesTextArea.Value = "";
                uialert(app.LabelRecognitionUIFigure, 'No dates were detected. Either there are no dates in the image or the parameters need to be relaxed.', 'Recognition Failure!');
            else
                % Update text area with dates
                app.DetectedDatesTextArea.Value = dates;
            end
        end
        
        function UpdateRuntimeLabel(app, duration, dates)
            if isempty(dates)
                % Don't display runtime if unsuccessful
                ShowRuntimeAsOutdated(app);
            else
                % Make label visible
                app.RuntimeLabel.Visible = 'on';
                % Set font colour to black
                app.RuntimeLabel.FontColor = 'black';
                % Display Runtime
                app.RuntimeLabel.Text = sprintf("Done in: %.3fs", duration);
            end
        end
        
        function ShowRuntimeAsOutdated(app)
            % Set font colout to grey
            app.RuntimeLabel.FontColor = '#808080';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          
            % Set default slider values
            app.MSERDeltaSlider.Value = app.lr.thresholdDelta;
            app.SWTThresholdSlider.Value = app.lr.strokeWidthVariationThreshold;
            app.OrientationThresholdSlider.Value = app.lr.orientationThreshold;
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            
            % Select an image from explorer UI and assign to app
            SelectImage(app);
            
            % Return to UIFigure
            drawnow;
            figure(app.LabelRecognitionUIFigure);
        end

        % Button pushed function: RecogniseDatesButton
        function RecogniseDatesButtonPushed(app, event)
           
            % Check to see if an image has been selected.
            if ~isempty(app.lr.image)
                try
                    % Start timer
                    tic;
                    % Perform recognition
                    dates = app.lr.recogniseDates();
                    % Stop timer
                    duration = toc;
                    
                    % Update text area, save button status and runtime label
                    UpdateDatesTextArea(app, dates);
                    ToggleSaveButtonStatus(app);
                    UpdateRuntimeLabel(app, duration, dates);
                catch ME
                    % Remove old runtime and date info
                    app.DetectedDatesTextArea.Value = "";
                    ToggleSaveButtonStatus(app);
                    ShowRuntimeAsOutdated(app);
                    
                    switch ME.identifier
                        case 'LabelRecogniser:InvalidMSERParams'
                            uialert(app.LabelRecognitionUIFigure, 'The MSER algorithm is unable to detect any dates. Please reduce the MSER Delta value', 'Recognition Failure!');
                        case 'LabelRecogniser:NoBoundingBoxes'
                            uialert(app.LabelRecognitionUIFigure, 'No dates were detected. Either there are no dates in the image or the parameters need to be relaxed.', 'Recognition Failure!');
                        otherwise
                            uialert(app.LabelRecognitionUIFigure, 'An unexpected error occured', 'Recognition Failure!');
                    end
                end
            else
                uialert(app.LabelRecognitionUIFigure, 'An image must be provided for recognition.', 'Recognition Failure!');
            end                        
        end

        % Value changed function: MSERDeltaSlider
        function MSERDeltaSliderValueChanged(app, event)
            %Set new threshold Delta value
            app.lr.thresholdDelta = app.MSERDeltaSlider.Value;
            % Mark the shown runtime as outdated
            ShowRuntimeAsOutdated(app);
        end

        % Value changed function: SWTThresholdSlider
        function SWTThresholdSliderValueChanged(app, event)
            %Set new SWT threshold value
            app.lr.strokeWidthVariationThreshold = app.SWTThresholdSlider.Value;
            % Mark the shown runtime as outdated
            ShowRuntimeAsOutdated(app);
        end

        % Value changed function: OrientationThresholdSlider
        function OrientationThresholdSliderValueChanged(app, event)
            % Set new Orientation threshold value
            app.lr.orientationThreshold = app.OrientationThresholdSlider.Value;
            % Mark the shown runtime as outdated
            ShowRuntimeAsOutdated(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            SaveDateFile(app);
            
            % Return to UIFigure
            drawnow;
            figure(app.LabelRecognitionUIFigure);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % Define default values
            mserDefault = 2.5; SWTDefault = 0.4; orientationDefault = 7.5;          
            
            % Set MSER Delta slider & LabelRecogniser object to default values
            app.MSERDeltaSlider.Value = mserDefault;
            app.lr.thresholdDelta = mserDefault;
            
            % Set SWT Threshold slider & LabelRecogniser object to default values
            app.SWTThresholdSlider.Value = SWTDefault;
            app.lr.strokeWidthVariationThreshold = SWTDefault;
            
            % Set orientation Threshold slider & LabelRecogniser object 
            % to default values
            app.OrientationThresholdSlider.Value = orientationDefault;
            app.lr.orientationThreshold = orientationDefault;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.LabelRecognitionUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {234, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LabelRecognitionUIFigure = uifigure('Visible', 'off');
            app.LabelRecognitionUIFigure.Name = 'Label Recognition';
            app.LabelRecognitionUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.LabelRecognitionUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftGridLayout = uigridlayout(app.LeftPanel);
            app.FileLocationField = uieditfield(app.LeftGridLayout, 'text');
            app.FileLocationField.Editable = 'off';
            app.FileLocationField.Tooltip = {''};
            app.FileLocationField.Value = 'Select an Image File';
            app.BrowseButton = uibutton(app.LeftGridLayout, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.RecogniseDatesButton = uibutton(app.LeftGridLayout, 'push');
            app.RecogniseDatesButton.ButtonPushedFcn = createCallbackFcn(app, @RecogniseDatesButtonPushed, true);
            app.RecogniseDatesButton.Text = 'Recognise Dates';
            app.ConfigurationLabel = uilabel(app.LeftGridLayout);
            app.ConfigurationLabel.Text = 'Configuration';
            app.MSERDeltaLabel = uilabel(app.LeftGridLayout);
            app.MSERDeltaLabel.Tooltip = {'Sensitivity of text detection. Lower values return more regions. Typical values range from 0.8 to 4.'};
            app.MSERDeltaLabel.Text = 'MSER Delta:';
            app.MSERDeltaSlider = uislider(app.LeftGridLayout);
            app.MSERDeltaSlider.Limits = [0.1 10];
            app.MSERDeltaSlider.MajorTicks = [0.1 2.5 5 7.5 10];
            app.MSERDeltaSlider.ValueChangedFcn = createCallbackFcn(app, @MSERDeltaSliderValueChanged, true);
            app.MSERDeltaSlider.MinorTicks = [0.1 0.5 1 1.5 2 2.5 3 3.5 4 4.5 5 5.5 6 6.5 7 8 8.5 9 9.5 10 75];
            app.MSERDeltaSlider.Value = 2.4;
            app.SWTThresholdLabel = uilabel(app.LeftGridLayout);
            app.SWTThresholdLabel.Tooltip = {'Max difference in candidate stroke widths. Lower values are stricter & discard more candidates. Typical values are around 0.4.'};
            app.SWTThresholdLabel.Text = 'SWT Threshold:';
            app.SWTThresholdSlider = uislider(app.LeftGridLayout);
            app.SWTThresholdSlider.Limits = [0.1 1];
            app.SWTThresholdSlider.MajorTicks = [0.1 0.25 0.5 0.75 1];
            app.SWTThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @SWTThresholdSliderValueChanged, true);
            app.SWTThresholdSlider.MinorTicks = [0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5 0.55 0.6 0.65 0.7 0.75 0.8 0.85 0.9 0.95 1];
            app.SWTThresholdSlider.Tooltip = {''};
            app.SWTThresholdSlider.Value = 0.4;
            app.OrientationThresholdLabel = uilabel(app.LeftGridLayout);
            app.OrientationThresholdLabel.Tooltip = {'Orientation that must be exceeded for candidates to be corrected. Lower values are stricter & increase the number of corrections. Typical values are around 7.5°.'};
            app.OrientationThresholdLabel.Text = 'Orientation Threshold:';
            app.OrientationThresholdSlider = uislider(app.LeftGridLayout);
            app.OrientationThresholdSlider.Limits = [0 45];
            app.OrientationThresholdSlider.MajorTicks = [0 5 10 15 20 25 30 35 40 45];
            app.OrientationThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @OrientationThresholdSliderValueChanged, true);
            app.OrientationThresholdSlider.Value = 7.5;
            app.DetectedDatesPanel = uipanel(app.LeftGridLayout);
            app.DetectedDatesPanel.Title = 'Detected Dates';
            app.DetectedDatesGridLayout = uigridlayout(app.DetectedDatesPanel);
            app.SaveButton = uibutton(app.DetectedDatesGridLayout, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save...';
            app.DetectedDatesTextArea = uitextarea(app.DetectedDatesGridLayout);
            app.DetectedDatesTextArea.Editable = 'off';
            app.ResetButton = uibutton(app.LeftGridLayout, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.RightPanel = uipanel(app.GridLayout);
            app.RightGridLayout = uigridlayout(app.RightPanel);
            app.Image = uiimage(app.RightGridLayout);
            app.Image.Visible = 'off';
            app.RuntimeLabel = uilabel(app.RightGridLayout);
            app.RuntimeLabel.Visible = 'off';
            app.RuntimeLabel.Text = 'Done in: 0.000s';
            % Show the figure after all components are created
            app.LabelRecognitionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LabelRecognition

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LabelRecognitionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LabelRecognitionUIFigure)
        end
    end
end